classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Panel                        matlab.ui.container.Panel
        Label_6                      matlab.ui.control.Label
        Lamp                         matlab.ui.control.Lamp
        Label_7                      matlab.ui.control.Label
        EditField                    matlab.ui.control.NumericEditField
        Label_8                      matlab.ui.control.Label
        Gauge                        matlab.ui.control.Gauge
        Gauge_2Label                 matlab.ui.control.Label
        Gauge_2                      matlab.ui.control.Gauge
        Gauge_3Label                 matlab.ui.control.Label
        Gauge_3                      matlab.ui.control.Gauge
        Label_9                      matlab.ui.control.Label
        Gauge_4                      matlab.ui.control.Gauge
        Label_4                      matlab.ui.control.Label
        gnss_mode                    matlab.ui.control.DiscreteKnob
        Panel_2                      matlab.ui.container.Panel
        roverbutton                  matlab.ui.control.Button
        Label_10                     matlab.ui.control.Label
        rovereditfiled               matlab.ui.control.EditField
        basebutton                   matlab.ui.control.Button
        Label_11                     matlab.ui.control.Label
        baseeditfiled                matlab.ui.control.EditField
        brdbutton                    matlab.ui.control.Button
        Label_12                     matlab.ui.control.Label
        brdeditfiled                 matlab.ui.control.EditField
        precEpheButton               matlab.ui.control.Button
        Label_13                     matlab.ui.control.Label
        precEpheFiled                matlab.ui.control.EditField
        precClockButton              matlab.ui.control.Button
        Label_14                     matlab.ui.control.Label
        precClockFiled               matlab.ui.control.EditField
        ATXbutton                    matlab.ui.control.Button
        ATXLabel                     matlab.ui.control.Label
        ATXfiled                     matlab.ui.control.EditField
        DCBbutton                    matlab.ui.control.Button
        DCBLabel                     matlab.ui.control.Label
        DCBfiled                     matlab.ui.control.EditField
        MGEXbutton                   matlab.ui.control.Button
        MGEXLabel                    matlab.ui.control.Label
        MGEXfiled                    matlab.ui.control.EditField
        EOPbutton                    matlab.ui.control.Button
        EOPLabel                     matlab.ui.control.Label
        EOPfiled                     matlab.ui.control.EditField
        BLQbutton                    matlab.ui.control.Button
        BLQLabel                     matlab.ui.control.Label
        BLQfiled                     matlab.ui.control.EditField
        IMUbutton                    matlab.ui.control.Button
        IMULabel                     matlab.ui.control.Label
        IMUfiled                     matlab.ui.control.EditField
        readfileButton               matlab.ui.control.Button
        savedataButton               matlab.ui.control.Button
        loaddataButton               matlab.ui.control.Button
        runbutton                    matlab.ui.control.Button
        Panel_4                      matlab.ui.container.Panel
        Panel_5                      matlab.ui.container.Panel
        checkgps                     matlab.ui.control.CheckBox
        checkglo                     matlab.ui.control.CheckBox
        checkgal                     matlab.ui.control.CheckBox
        checkbds                     matlab.ui.control.CheckBox
        checkqzss                    matlab.ui.control.CheckBox
        Panel_6                      matlab.ui.container.Panel
        elmin                        matlab.ui.control.Slider
        Panel_7                      matlab.ui.container.Panel
        armode                       matlab.ui.control.DiscreteKnob
        ratio                        matlab.ui.control.NumericEditField
        lambdaswitch                 matlab.ui.control.Switch
        lambdalabel                  matlab.ui.control.Label
        successRate                  matlab.ui.control.NumericEditField
        lambdalabel_2                matlab.ui.control.Label
        ProcessParametrer            matlab.ui.container.Panel
        PosParameter_2Label          matlab.ui.control.Label
        PosNoise                     matlab.ui.control.NumericEditField
        RecClkParameter_2Label       matlab.ui.control.Label
        RecClkNoise                  matlab.ui.control.NumericEditField
        TropParameter_2Label         matlab.ui.control.Label
        TropNoise                    matlab.ui.control.NumericEditField
        AmbParameter_2Label          matlab.ui.control.Label
        AmbNoise                     matlab.ui.control.NumericEditField
        PosNoise2_2Label             matlab.ui.control.Label
        RecClkNoise2                 matlab.ui.control.NumericEditField
        PosNoise2_3Label             matlab.ui.control.Label
        TropNoise2                   matlab.ui.control.NumericEditField
        PosNoise2_4Label             matlab.ui.control.Label
        AmbNoise2                    matlab.ui.control.NumericEditField
        PosNLabel                    matlab.ui.control.Label
        RecClkNLabel                 matlab.ui.control.Label
        TropNLabel                   matlab.ui.control.Label
        AmbNLabel                    matlab.ui.control.Label
        STDNLabel                    matlab.ui.control.Label
        SystemTimeDifferenceLabel    matlab.ui.control.Label
        STDNoise                     matlab.ui.control.NumericEditField
        AmbNoise2_2Label             matlab.ui.control.Label
        STDNoise2                    matlab.ui.control.NumericEditField
        AmbNoise2_2Label_2           matlab.ui.control.Label
        PosNoise2                    matlab.ui.control.NumericEditField
        TropNLabel_2                 matlab.ui.control.Label
        AmbNLabel_2                  matlab.ui.control.Label
        STDNLabel_2                  matlab.ui.control.Label
        CodeBiasLabel_2              matlab.ui.control.Label
        CBNoise                      matlab.ui.control.NumericEditField
        PhaseBiasLabel_2             matlab.ui.control.Label
        PBNoise                      matlab.ui.control.NumericEditField
        PosNoise2_3Label_2           matlab.ui.control.Label
        CBNoise2                     matlab.ui.control.NumericEditField
        PosNoise2_4Label_2           matlab.ui.control.Label
        PBNoise2                     matlab.ui.control.NumericEditField
        IFBLabel_2                   matlab.ui.control.Label
        IFBNoise                     matlab.ui.control.NumericEditField
        AmbNoise2_2Label_3           matlab.ui.control.Label
        IFBNoise2                    matlab.ui.control.NumericEditField
        RecClkNLabel_2               matlab.ui.control.Label
        InonsphereLabel_2            matlab.ui.control.Label
        InoNoise                     matlab.ui.control.NumericEditField
        PosNoise2_2Label_3           matlab.ui.control.Label
        InoNoise2                    matlab.ui.control.NumericEditField
        STDNLabel_3                  matlab.ui.control.Label
        SatelliteClockLabel_2        matlab.ui.control.Label
        SatClkNoise                  matlab.ui.control.NumericEditField
        AmbNoise2_2Label_4           matlab.ui.control.Label
        SatClkNoise2                 matlab.ui.control.NumericEditField
        MeasurementOption            matlab.ui.container.Panel
        WeightMethodLabel            matlab.ui.control.Label
        MeasWeight                   matlab.ui.control.DropDown
        MeasNoiseLabel               matlab.ui.control.Label
        CodeMeasLabel                matlab.ui.control.Label
        CodeMeas                     matlab.ui.control.NumericEditField
        PhaseMeasLabel               matlab.ui.control.Label
        PhaseMeas                    matlab.ui.control.NumericEditField
        mLabel_5                     matlab.ui.control.Label
        mLabel_4                     matlab.ui.control.Label
        Panel_8                      matlab.ui.container.Panel
        XEditFieldLabel              matlab.ui.control.Label
        basex                        matlab.ui.control.NumericEditField
        YEditFieldLabel              matlab.ui.control.Label
        basey                        matlab.ui.control.NumericEditField
        ZEditFieldLabel              matlab.ui.control.Label
        basez                        matlab.ui.control.NumericEditField
        mLabel_6                     matlab.ui.control.Label
        mLabel_7                     matlab.ui.control.Label
        mLabel_8                     matlab.ui.control.Label
        roverx                       matlab.ui.control.NumericEditField
        rovery                       matlab.ui.control.NumericEditField
        roverz                       matlab.ui.control.NumericEditField
        mLabel_9                     matlab.ui.control.Label
        mLabel_10                    matlab.ui.control.Label
        mLabel_11                    matlab.ui.control.Label
        InitialParameter             matlab.ui.container.Panel
        PositionEditFieldLabel       matlab.ui.control.Label
        PosParameter                 matlab.ui.control.NumericEditField
        ReceiverClockLabel           matlab.ui.control.Label
        RecClkParameter              matlab.ui.control.NumericEditField
        TrophosphereLabel            matlab.ui.control.Label
        TropParameter                matlab.ui.control.NumericEditField
        PhaseAmbiguitiesLabel        matlab.ui.control.Label
        AmbParameter                 matlab.ui.control.NumericEditField
        PosLabel                     matlab.ui.control.Label
        PosNoise2_2Label_2           matlab.ui.control.Label
        PosParameter2                matlab.ui.control.NumericEditField
        RecClkLabel2                 matlab.ui.control.Label
        PosParameter2_2Label         matlab.ui.control.Label
        RecClkParameter2             matlab.ui.control.NumericEditField
        tropLabel                    matlab.ui.control.Label
        PosParameter2_3Label         matlab.ui.control.Label
        TropParameter2               matlab.ui.control.NumericEditField
        AmbLabel                     matlab.ui.control.Label
        PosParameter2_4Label         matlab.ui.control.Label
        AmbParameter2                matlab.ui.control.NumericEditField
        SystemTimeDifferenceLabel_2  matlab.ui.control.Label
        STDParameter                 matlab.ui.control.NumericEditField
        AmbParameter2_2Label         matlab.ui.control.Label
        STDParameter2                matlab.ui.control.NumericEditField
        STDLabel                     matlab.ui.control.Label
        STDLabel_2                   matlab.ui.control.Label
        AmbParameter2_2Label_2       matlab.ui.control.Label
        CBParameter2                 matlab.ui.control.NumericEditField
        CodeBiasLabel                matlab.ui.control.Label
        CBParameter                  matlab.ui.control.NumericEditField
        STDLabel_3                   matlab.ui.control.Label
        AmbParameter2_2Label_3       matlab.ui.control.Label
        InoParameter2                matlab.ui.control.NumericEditField
        InonsphereLabel              matlab.ui.control.Label
        InoParameter                 matlab.ui.control.NumericEditField
        STDLabel_4                   matlab.ui.control.Label
        AmbParameter2_2Label_4       matlab.ui.control.Label
        PBParameter2                 matlab.ui.control.NumericEditField
        PhaseBiasLabel               matlab.ui.control.Label
        PBParameter                  matlab.ui.control.NumericEditField
        STDLabel_5                   matlab.ui.control.Label
        AmbParameter2_2Label_5       matlab.ui.control.Label
        IFBParameter2                matlab.ui.control.NumericEditField
        IFBLabel                     matlab.ui.control.Label
        IFBParameter                 matlab.ui.control.NumericEditField
        STDLabel_6                   matlab.ui.control.Label
        AmbParameter2_2Label_6       matlab.ui.control.Label
        SatClkParameter2             matlab.ui.control.NumericEditField
        SatelliteClockLabel          matlab.ui.control.Label
        SatClkParameter              matlab.ui.control.NumericEditField
        basepostype                  matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        glc  = struct();
        gls  = struct(); % Description
        opt  = struct('LAMBDAtype', 1,'mode',4,'modear',3);
        file = struct();
        res  = struct();
        obsr = struct();
        obsb = struct();
        nav  = struct();
        pathname = '';
        isLoadData = 0; %数据通过读取获得
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 设置全局变量
            [app.glc,app.gls,app.opt,app.file]=global_variable5();
            % default parameter and setup for app
            app.opt.modear      =  3;           % 模糊度固定策略 fix and hold
            app.opt.mode        =  4;           % ppps
            app.opt.thresar     =  [3.0,0.9995];% Ration and SuccessRate
            app.opt.LAMBDAtype  =  1;           % all AR
        end

        % Button pushed function: basebutton
        function basebutton_func(app, event)
            [tempfile,path] = uigetfile('*.*','Select the base station observation file',app.pathname);
            if ischar(tempfile)
                app.pathname=path;
                base = fullfile(path,tempfile);
                app.baseeditfiled.Editable       = 'on';
                app.baseeditfiled.Value          = tempfile;
                app.baseeditfiled.Editable       = 'off';
                app.file.obsb=base;
            else
                return
            end
        end

        % Button pushed function: roverbutton
        function rvoerbutton_func(app, event)
            [tempfile,path] = uigetfile('*.*','Select the rover station observation file',app.pathname);
            if ischar(tempfile)
                app.pathname=path;
                rover = fullfile(path,tempfile);
                app.rovereditfiled.Editable       = 'on';
                app.rovereditfiled.Value          = tempfile;
                app.rovereditfiled.Editable       = 'off';
                app.file.obsr=rover;
            else
                return
            end
        end

        % Button pushed function: brdbutton
        function brdbutton_func(app, event)
            [tempfile,path] = uigetfile('*.*','Select the broadcast ephemeris file',app.pathname);
            if ischar(tempfile)
                app.pathname=path;
                brd = fullfile(path,tempfile);
                app.brdeditfiled.Editable       = 'on';
                app.brdeditfiled.Value          = tempfile;
                app.brdeditfiled.Editable       = 'off';
                app.file.beph=brd;
            else
                return
            end
        end

        % Value changed function: armode
        function armode_func(app, event)
            value = app.armode.Value;
            if strcmp(value,'Off')
                app.lambdaswitch.Visible='off';
                app.lambdalabel.Visible='off';
                app.ratio.Visible='off';
                app.opt.modear = 0;
            elseif strcmp(value,'cont')
                app.lambdaswitch.Visible='on';
                app.lambdalabel.Visible='on';
                app.ratio.Visible='on';
                app.opt.modear = 1;
            elseif strcmp(value,'inst')
                app.lambdaswitch.Visible='on';
                app.lambdalabel.Visible='on';
                app.ratio.Visible='on';
                app.opt.modear = 2;
            elseif strcmp(value,'hold')
                app.lambdaswitch.Visible='on';
                app.lambdalabel.Visible='on';
                app.ratio.Visible='on';
                app.opt.modear = 3;
            end
        end

        % Value changed function: lambdaswitch
        function lambdaswith_func(app, event)
            value = app.lambdaswitch.Value;
            if strcmp(value,'all AR')
                app.opt.LAMBDAtype = 1;
            elseif strcmp(value,'PAR')
                app.opt.LAMBDAtype = 2;
            end
        end

        % Value changed function: gnss_mode
        function gnssmode_func(app, event)
            value = app.gnss_mode.Value;
            if strcmp(value,'SPP')
                app.opt.mode = 1;
            elseif strcmp(value,'PPD')
                app.opt.mode = 2;
            elseif strcmp(value,'PPS')
                app.opt.mode = 4;
            elseif strcmp(value,'PPK')
                app.opt.mode = 3;
            elseif strcmp(value,'PPP')
                app.opt.mode = 5;
            end
        end

        % Button pushed function: runbutton
        function runbutton_func(app, event)
            if app.isLoadData
                [app,res]=exepos7(app,app.glc,app.gls,app.opt,app.file,app.res,app.obsr,app.obsb,app.nav);
            else
                [app,res]=exepos6(app,app.glc,app.gls,app.opt,app.file,app.res);
            end

        end

        % Button pushed function: precEpheButton
        function precEpheButton_func(app, event)
            [tempfile,path] = uigetfile('*.*','Select the precise ephemeris file',app.pathname);
            if ischar(tempfile)
                app.pathname=path;
                sp3 = fullfile(path,tempfile);
                app.precEpheFiled.Editable       = 'on';
                app.precEpheFiled.Value          = tempfile;
                app.precEpheFiled.Editable       = 'off';
                app.file.sp3=sp3;
            else
                return
            end
        end

        % Button pushed function: precClockButton
        function precClockButton_func(app, event)
            [tempfile,path] = uigetfile('*.*','Select the precise clock file',app.pathname);
            if ischar(tempfile)
                app.pathname=path;
                clk = fullfile(path,tempfile);
                app.precClockFiled.Editable       = 'on';
                app.precClockFiled.Value          = tempfile;
                app.precClockFiled.Editable       = 'off';
                app.file.clk=clk;
            else
                return
            end
        end

        % Button pushed function: ATXbutton
        function ATXbutton_func(app, event)
            [tempfile,path] = uigetfile('*.*','Select the antenna file',app.pathname);
            if ischar(tempfile)
                app.pathname=path;
                atx = fullfile(path,tempfile);
                app.ATXfiled.Editable       = 'on';
                app.ATXfiled.Value          = tempfile;
                app.ATXfiled.Editable       = 'off';
                app.file.atx=atx;
            else
                return
            end
        end

        % Button pushed function: DCBbutton
        function DCBbutton_func(app, event)
            for i=1:3
            [tempfile,path] = uigetfile('*.*','Select the DCB file',app.pathname);
            if ischar(tempfile)
                app.pathname=path;
                DCB = fullfile(path,tempfile);
                app.DCBfiled.Editable       = 'on';
                app.DCBfiled.Value          = tempfile;
                app.DCBfiled.Editable       = 'off';
                app.file.dcb{1,i}=DCB;
            else
                return
            end
            end
        end

        % Button pushed function: MGEXbutton
        function MGEXbutton_func(app, event)
            [tempfile,path] = uigetfile('*.*','Select the MEGX DCB file',app.pathname);
            if ischar(tempfile)
                app.pathname=path;
                mgex = fullfile(path,tempfile);
                app.MGEXfiled.Editable       = 'on';
                app.MGEXfiled.Value          = tempfile;
                app.MGEXfiled.Editable       = 'off';
                app.file.dcb_mgex=mgex;
            else
                return
            end
        end

        % Button pushed function: EOPbutton
        function EOPbutton_func(app, event)
            [tempfile,path] = uigetfile('*.*','Select the ERP file',app.pathname);
            if ischar(tempfile)
                app.pathname=path;
                eop = fullfile(path,tempfile);
                app.EOPfiled.Editable       = 'on';
                app.EOPfiled.Value          = tempfile;
                app.EOPfiled.Editable       = 'off';
                app.file.erp=eop;
            else
                return
            end
        end

        % Button pushed function: BLQbutton
        function BLQbutton_func(app, event)
            [tempfile,path] = uigetfile('*.*','Select the BLQ file',app.pathname);
            if ischar(tempfile)
                app.pathname=path;
                blq = fullfile(path,tempfile);
                app.BLQfiled.Editable       = 'on';
                app.BLQfiled.Value          = tempfile;
                app.BLQfiled.Editable       = 'off';
                app.file.blq=blq;
            else
                return
            end
        end

        % Button pushed function: IMUbutton
        function IMUbutton_func(app, event)
            [tempfile,path] = uigetfile('*.*','Select the IMU file',app.pathname);
            if ischar(tempfile)
                app.pathname=path;
                imu = fullfile(path,tempfile);
                app.IMUfiled.Editable       = 'on';
                app.IMUfiled.Value          = tempfile;
                app.IMUfiled.Editable       = 'off';
                app.file.imu=imu;
            else
                return
            end
        end

        % Button pushed function: readfileButton
        function readfileButton_func(app, event)
            clc;
            [app.obsr,app.obsb,app.nav]=read_file(app.glc,app.gls,app.opt,app.file);
            app.isLoadData = 1;
            msgbox('读取数据完成！')
        end

        % Button pushed function: savedataButton
        function savedataButton_func(app, event)
            obsr = app.obsr;
            obsb = app.obsb;
            nav  = app.nav;
            file = app.file;
            uisave({'obsr', 'obsb','nav','file'})
        end

        % Button pushed function: loaddataButton
        function loaddataButton_func(app, event)
            [tempfile,path] = uigetfile('*.mat','加载数据');
            if ischar(tempfile)
                tmp = fullfile(path,tempfile);
                load(tmp);
                app.obsr = obsr;
                app.obsb = obsb;
                app.nav  = nav;
                app.file = file;
                app.rovereditfiled.Value = file.obsr;
                app.baseeditfiled.Value  = file.obsb;
                app.brdeditfiled.Value   = file.beph;
                app.precEpheFiled.Value  = file.sp3;
                app.precClockFiled.Value = file.clk;
                app.ATXfiled.Value       = file.atx;
                app.DCBfiled.Value       = file.dcb{1};
                app.MGEXfiled.Value      = file.dcb_mgex;
                app.EOPfiled.Value       = file.erp;
                app.BLQfiled.Value       = file.blq;
                app.IMUfiled.Value       = file.imu;
                app.isLoadData = 1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '运行状态';
            app.Label_6 = uilabel(app.Panel);
            app.Label_6.Text = '模糊度';
            app.Lamp = uilamp(app.Panel);
            app.Label_7 = uilabel(app.Panel);
            app.Label_7.Text = '历元';
            app.EditField = uieditfield(app.Panel, 'numeric');
            app.Label_8 = uilabel(app.Panel);
            app.Label_8.Text = '基准站';
            app.Gauge = uigauge(app.Panel, 'circular');
            app.Gauge.Limits = [0 60];
            app.Gauge_2Label = uilabel(app.Panel);
            app.Gauge_2Label.Text = '流动站';
            app.Gauge_2 = uigauge(app.Panel, 'circular');
            app.Gauge_2.Limits = [0 60];
            app.Gauge_3Label = uilabel(app.Panel);
            app.Gauge_3Label.Text = '共识卫星';
            app.Gauge_3 = uigauge(app.Panel, 'circular');
            app.Gauge_3.Limits = [0 60];
            app.Label_9 = uilabel(app.Panel);
            app.Label_9.Text = '固定数';
            app.Gauge_4 = uigauge(app.Panel, 'circular');
            app.Gauge_4.Limits = [0 60];
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '解算模式';
            app.gnss_mode = uiknob(app.Panel, 'discrete');
            app.gnss_mode.Items = {'PPS', 'PPD', 'PPK', 'SPP', 'PPP'};
            app.gnss_mode.ValueChangedFcn = createCallbackFcn(app, @gnssmode_func, true);
            app.gnss_mode.Value = 'PPS';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '文件';
            app.roverbutton = uibutton(app.Panel_2, 'push');
            app.roverbutton.ButtonPushedFcn = createCallbackFcn(app, @rvoerbutton_func, true);
            app.roverbutton.Text = '';
            app.Label_10 = uilabel(app.Panel_2);
            app.Label_10.Text = '流动站';
            app.rovereditfiled = uieditfield(app.Panel_2, 'text');
            app.basebutton = uibutton(app.Panel_2, 'push');
            app.basebutton.ButtonPushedFcn = createCallbackFcn(app, @basebutton_func, true);
            app.basebutton.Text = '';
            app.Label_11 = uilabel(app.Panel_2);
            app.Label_11.Text = '基准站';
            app.baseeditfiled = uieditfield(app.Panel_2, 'text');
            app.brdbutton = uibutton(app.Panel_2, 'push');
            app.brdbutton.ButtonPushedFcn = createCallbackFcn(app, @brdbutton_func, true);
            app.brdbutton.Text = '';
            app.Label_12 = uilabel(app.Panel_2);
            app.Label_12.Text = '广播星历';
            app.brdeditfiled = uieditfield(app.Panel_2, 'text');
            app.precEpheButton = uibutton(app.Panel_2, 'push');
            app.precEpheButton.ButtonPushedFcn = createCallbackFcn(app, @precEpheButton_func, true);
            app.precEpheButton.Text = '';
            app.Label_13 = uilabel(app.Panel_2);
            app.Label_13.Text = '精密星历';
            app.precEpheFiled = uieditfield(app.Panel_2, 'text');
            app.precClockButton = uibutton(app.Panel_2, 'push');
            app.precClockButton.ButtonPushedFcn = createCallbackFcn(app, @precClockButton_func, true);
            app.precClockButton.Text = '';
            app.Label_14 = uilabel(app.Panel_2);
            app.Label_14.Text = '精密钟差';
            app.precClockFiled = uieditfield(app.Panel_2, 'text');
            app.ATXbutton = uibutton(app.Panel_2, 'push');
            app.ATXbutton.ButtonPushedFcn = createCallbackFcn(app, @ATXbutton_func, true);
            app.ATXbutton.Text = '';
            app.ATXLabel = uilabel(app.Panel_2);
            app.ATXLabel.Text = 'ATX';
            app.ATXfiled = uieditfield(app.Panel_2, 'text');
            app.DCBbutton = uibutton(app.Panel_2, 'push');
            app.DCBbutton.ButtonPushedFcn = createCallbackFcn(app, @DCBbutton_func, true);
            app.DCBbutton.Text = '';
            app.DCBLabel = uilabel(app.Panel_2);
            app.DCBLabel.Text = 'DCB';
            app.DCBfiled = uieditfield(app.Panel_2, 'text');
            app.MGEXbutton = uibutton(app.Panel_2, 'push');
            app.MGEXbutton.ButtonPushedFcn = createCallbackFcn(app, @MGEXbutton_func, true);
            app.MGEXbutton.Text = '';
            app.MGEXLabel = uilabel(app.Panel_2);
            app.MGEXLabel.Text = 'MGEX';
            app.MGEXfiled = uieditfield(app.Panel_2, 'text');
            app.EOPbutton = uibutton(app.Panel_2, 'push');
            app.EOPbutton.ButtonPushedFcn = createCallbackFcn(app, @EOPbutton_func, true);
            app.EOPbutton.Text = '';
            app.EOPLabel = uilabel(app.Panel_2);
            app.EOPLabel.Text = 'EOP';
            app.EOPfiled = uieditfield(app.Panel_2, 'text');
            app.BLQbutton = uibutton(app.Panel_2, 'push');
            app.BLQbutton.ButtonPushedFcn = createCallbackFcn(app, @BLQbutton_func, true);
            app.BLQbutton.Text = '';
            app.BLQLabel = uilabel(app.Panel_2);
            app.BLQLabel.Text = 'BLQ';
            app.BLQfiled = uieditfield(app.Panel_2, 'text');
            app.IMUbutton = uibutton(app.Panel_2, 'push');
            app.IMUbutton.ButtonPushedFcn = createCallbackFcn(app, @IMUbutton_func, true);
            app.IMUbutton.Text = '';
            app.IMULabel = uilabel(app.Panel_2);
            app.IMULabel.Text = 'IMU';
            app.IMUfiled = uieditfield(app.Panel_2, 'text');
            app.readfileButton = uibutton(app.Panel_2, 'push');
            app.readfileButton.ButtonPushedFcn = createCallbackFcn(app, @readfileButton_func, true);
            app.readfileButton.Text = '读取文件';
            app.savedataButton = uibutton(app.Panel_2, 'push');
            app.savedataButton.ButtonPushedFcn = createCallbackFcn(app, @savedataButton_func, true);
            app.savedataButton.Text = '保存文件';
            app.loaddataButton = uibutton(app.Panel_2, 'push');
            app.loaddataButton.ButtonPushedFcn = createCallbackFcn(app, @loaddataButton_func, true);
            app.loaddataButton.Text = '加载数据';
            app.runbutton = uibutton(app.UIFigure, 'push');
            app.runbutton.ButtonPushedFcn = createCallbackFcn(app, @runbutton_func, true);
            app.runbutton.Text = '运行';
            app.Panel_4 = uipanel(app.UIFigure);
            app.Panel_4.Title = '参数配置';
            app.Panel_5 = uipanel(app.Panel_4);
            app.Panel_5.Title = '系统选择';
            app.checkgps = uicheckbox(app.Panel_5);
            app.checkgps.Text = 'GPS';
            app.checkgps.Value = true;
            app.checkglo = uicheckbox(app.Panel_5);
            app.checkglo.Text = 'GLONASS';
            app.checkglo.Value = true;
            app.checkgal = uicheckbox(app.Panel_5);
            app.checkgal.Text = 'GALILEO';
            app.checkgal.Value = true;
            app.checkbds = uicheckbox(app.Panel_5);
            app.checkbds.Text = 'BDS';
            app.checkbds.Value = true;
            app.checkqzss = uicheckbox(app.Panel_5);
            app.checkqzss.Text = 'QZSS';
            app.checkqzss.Value = true;
            app.Panel_6 = uipanel(app.Panel_4);
            app.Panel_6.Title = '截止高度角（角度）';
            app.elmin = uislider(app.Panel_6);
            app.elmin.Limits = [0 20];
            app.elmin.MajorTicks = [0 5 10 15 20];
            app.elmin.MajorTickLabels = {'0', '5', '10', '15', '20'};
            app.elmin.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.elmin.Value = 15;
            app.Panel_7 = uipanel(app.Panel_4);
            app.Panel_7.Title = '模糊度策略';
            app.armode = uiknob(app.Panel_7, 'discrete');
            app.armode.Items = {'Off', 'cont', 'inst', 'hold'};
            app.armode.ValueChangedFcn = createCallbackFcn(app, @armode_func, true);
            app.armode.Value = 'hold';
            app.ratio = uieditfield(app.Panel_7, 'numeric');
            app.ratio.Value = 3;
            app.lambdaswitch = uiswitch(app.Panel_7, 'slider');
            app.lambdaswitch.Items = {'all AR', 'PAR'};
            app.lambdaswitch.ValueChangedFcn = createCallbackFcn(app, @lambdaswith_func, true);
            app.lambdaswitch.Value = 'all AR';
            app.lambdalabel = uilabel(app.Panel_7);
            app.lambdalabel.Text = 'Ratio';
            app.successRate = uieditfield(app.Panel_7, 'numeric');
            app.successRate.Value = 0.9995;
            app.lambdalabel_2 = uilabel(app.Panel_7);
            app.lambdalabel_2.Text = 'Success Rate';
            app.ProcessParametrer = uipanel(app.Panel_4);
            app.ProcessParametrer.Title = 'Select Process Uncertainty Parameters';
            app.PosParameter_2Label = uilabel(app.ProcessParametrer);
            app.PosParameter_2Label.Text = 'Position';
            app.PosNoise = uieditfield(app.ProcessParametrer, 'numeric');
            app.PosNoise.ValueDisplayFormat = '%.1f';
            app.PosNoise.Editable = 'off';
            app.RecClkParameter_2Label = uilabel(app.ProcessParametrer);
            app.RecClkParameter_2Label.Text = 'Receiver Clock';
            app.RecClkNoise = uieditfield(app.ProcessParametrer, 'numeric');
            app.RecClkNoise.ValueDisplayFormat = '%.13f';
            app.RecClkNoise.Value = 1;
            app.TropParameter_2Label = uilabel(app.ProcessParametrer);
            app.TropParameter_2Label.Text = 'Trophosphere';
            app.TropNoise = uieditfield(app.ProcessParametrer, 'numeric');
            app.TropNoise.ValueDisplayFormat = '%.1f';
            app.TropNoise.Value = 1;
            app.AmbParameter_2Label = uilabel(app.ProcessParametrer);
            app.AmbParameter_2Label.Text = 'Phase Ambiguities';
            app.AmbNoise = uieditfield(app.ProcessParametrer, 'numeric');
            app.AmbNoise.ValueDisplayFormat = '%.1f';
            app.AmbNoise.Editable = 'off';
            app.PosNoise2_2Label = uilabel(app.ProcessParametrer);
            app.PosNoise2_2Label.Text = 'e';
            app.RecClkNoise2 = uieditfield(app.ProcessParametrer, 'numeric');
            app.RecClkNoise2.Value = 6;
            app.PosNoise2_3Label = uilabel(app.ProcessParametrer);
            app.PosNoise2_3Label.Text = 'e';
            app.TropNoise2 = uieditfield(app.ProcessParametrer, 'numeric');
            app.TropNoise2.Value = -9;
            app.PosNoise2_4Label = uilabel(app.ProcessParametrer);
            app.PosNoise2_4Label.Text = 'e';
            app.AmbNoise2 = uieditfield(app.ProcessParametrer, 'numeric');
            app.AmbNoise2.Editable = 'off';
            app.PosNLabel = uilabel(app.ProcessParametrer);
            app.PosNLabel.Text = 'm2/sec';
            app.RecClkNLabel = uilabel(app.ProcessParametrer);
            app.RecClkNLabel.Text = 'm2/sec';
            app.TropNLabel = uilabel(app.ProcessParametrer);
            app.TropNLabel.Text = 'm2/sec';
            app.AmbNLabel = uilabel(app.ProcessParametrer);
            app.AmbNLabel.Text = 'm2/sec';
            app.STDNLabel = uilabel(app.ProcessParametrer);
            app.STDNLabel.Text = 'm2/sec';
            app.SystemTimeDifferenceLabel = uilabel(app.ProcessParametrer);
            app.SystemTimeDifferenceLabel.Text = 'System Time Difference';
            app.STDNoise = uieditfield(app.ProcessParametrer, 'numeric');
            app.STDNoise.ValueDisplayFormat = '%.1f';
            app.STDNoise.Value = 1;
            app.AmbNoise2_2Label = uilabel(app.ProcessParametrer);
            app.AmbNoise2_2Label.Text = 'e';
            app.STDNoise2 = uieditfield(app.ProcessParametrer, 'numeric');
            app.STDNoise2.Value = -7;
            app.AmbNoise2_2Label_2 = uilabel(app.ProcessParametrer);
            app.AmbNoise2_2Label_2.Text = 'e';
            app.PosNoise2 = uieditfield(app.ProcessParametrer, 'numeric');
            app.PosNoise2.Editable = 'off';
            app.TropNLabel_2 = uilabel(app.ProcessParametrer);
            app.TropNLabel_2.Text = 'm2/sec';
            app.AmbNLabel_2 = uilabel(app.ProcessParametrer);
            app.AmbNLabel_2.Text = 'm2/sec';
            app.STDNLabel_2 = uilabel(app.ProcessParametrer);
            app.STDNLabel_2.Text = 'm2/sec';
            app.CodeBiasLabel_2 = uilabel(app.ProcessParametrer);
            app.CodeBiasLabel_2.Text = 'Code Bias';
            app.CBNoise = uieditfield(app.ProcessParametrer, 'numeric');
            app.CBNoise.ValueDisplayFormat = '%.1f';
            app.CBNoise.Value = 1;
            app.PhaseBiasLabel_2 = uilabel(app.ProcessParametrer);
            app.PhaseBiasLabel_2.Text = 'Phase Bias';
            app.PBNoise = uieditfield(app.ProcessParametrer, 'numeric');
            app.PBNoise.ValueDisplayFormat = '%.1f';
            app.PBNoise.Editable = 'off';
            app.PBNoise.Value = 1;
            app.PosNoise2_3Label_2 = uilabel(app.ProcessParametrer);
            app.PosNoise2_3Label_2.Text = 'e';
            app.CBNoise2 = uieditfield(app.ProcessParametrer, 'numeric');
            app.CBNoise2.Value = -7;
            app.PosNoise2_4Label_2 = uilabel(app.ProcessParametrer);
            app.PosNoise2_4Label_2.Text = 'e';
            app.PBNoise2 = uieditfield(app.ProcessParametrer, 'numeric');
            app.PBNoise2.Editable = 'off';
            app.PBNoise2.Value = -7;
            app.IFBLabel_2 = uilabel(app.ProcessParametrer);
            app.IFBLabel_2.Text = 'IFB';
            app.IFBNoise = uieditfield(app.ProcessParametrer, 'numeric');
            app.IFBNoise.ValueDisplayFormat = '%.1f';
            app.IFBNoise.Value = 1;
            app.AmbNoise2_2Label_3 = uilabel(app.ProcessParametrer);
            app.AmbNoise2_2Label_3.Text = 'e';
            app.IFBNoise2 = uieditfield(app.ProcessParametrer, 'numeric');
            app.IFBNoise2.Value = -7;
            app.RecClkNLabel_2 = uilabel(app.ProcessParametrer);
            app.RecClkNLabel_2.Text = 'm2/sec';
            app.InonsphereLabel_2 = uilabel(app.ProcessParametrer);
            app.InonsphereLabel_2.Text = 'Inonsphere';
            app.InoNoise = uieditfield(app.ProcessParametrer, 'numeric');
            app.InoNoise.ValueDisplayFormat = '%.13f';
            app.InoNoise.Value = 1;
            app.PosNoise2_2Label_3 = uilabel(app.ProcessParametrer);
            app.PosNoise2_2Label_3.Text = 'e';
            app.InoNoise2 = uieditfield(app.ProcessParametrer, 'numeric');
            app.InoNoise2.Value = -7;
            app.STDNLabel_3 = uilabel(app.ProcessParametrer);
            app.STDNLabel_3.Text = 'm2/sec';
            app.SatelliteClockLabel_2 = uilabel(app.ProcessParametrer);
            app.SatelliteClockLabel_2.Text = 'Satellite Clock';
            app.SatClkNoise = uieditfield(app.ProcessParametrer, 'numeric');
            app.SatClkNoise.ValueDisplayFormat = '%.1f';
            app.SatClkNoise.Value = 1;
            app.AmbNoise2_2Label_4 = uilabel(app.ProcessParametrer);
            app.AmbNoise2_2Label_4.Text = 'e';
            app.SatClkNoise2 = uieditfield(app.ProcessParametrer, 'numeric');
            app.SatClkNoise2.Value = -7;
            app.MeasurementOption = uipanel(app.Panel_4);
            app.MeasurementOption.Title = 'Measurement Noise and Weight';
            app.WeightMethodLabel = uilabel(app.MeasurementOption);
            app.WeightMethodLabel.Text = 'Weight Method';
            app.MeasWeight = uidropdown(app.MeasurementOption);
            app.MeasWeight.Items = {'Equal', 'Elevation Dependent'};
            app.MeasWeight.Value = 'Elevation Dependent';
            app.MeasNoiseLabel = uilabel(app.MeasurementOption);
            app.MeasNoiseLabel.Text = 'Measurement Standart Deviation';
            app.CodeMeasLabel = uilabel(app.MeasurementOption);
            app.CodeMeasLabel.Text = 'Code Meas.';
            app.CodeMeas = uieditfield(app.MeasurementOption, 'numeric');
            app.CodeMeas.ValueDisplayFormat = '%5.3f';
            app.CodeMeas.Value = 0.3;
            app.PhaseMeasLabel = uilabel(app.MeasurementOption);
            app.PhaseMeasLabel.Text = 'Phase Meas.';
            app.PhaseMeas = uieditfield(app.MeasurementOption, 'numeric');
            app.PhaseMeas.ValueDisplayFormat = '%5.3f';
            app.PhaseMeas.Value = 0.003;
            app.mLabel_5 = uilabel(app.MeasurementOption);
            app.mLabel_5.Text = 'm';
            app.mLabel_4 = uilabel(app.MeasurementOption);
            app.mLabel_4.Text = 'm';
            app.Panel_8 = uipanel(app.Panel_4);
            app.Panel_8.Title = '基准站和流动站坐标';
            app.XEditFieldLabel = uilabel(app.Panel_8);
            app.XEditFieldLabel.Text = 'X';
            app.basex = uieditfield(app.Panel_8, 'numeric');
            app.basex.ValueDisplayFormat = '%.4f';
            app.basex.Value = -2364335.6607;
            app.YEditFieldLabel = uilabel(app.Panel_8);
            app.YEditFieldLabel.Text = 'Y';
            app.basey = uieditfield(app.Panel_8, 'numeric');
            app.basey.ValueDisplayFormat = '%.4f';
            app.basey.Value = 4870281.4902;
            app.ZEditFieldLabel = uilabel(app.Panel_8);
            app.ZEditFieldLabel.Text = 'Z';
            app.basez = uieditfield(app.Panel_8, 'numeric');
            app.basez.ValueDisplayFormat = '%.4f';
            app.basez.Value = -3360816.4321;
            app.mLabel_6 = uilabel(app.Panel_8);
            app.mLabel_6.Text = 'm';
            app.mLabel_7 = uilabel(app.Panel_8);
            app.mLabel_7.Text = 'm';
            app.mLabel_8 = uilabel(app.Panel_8);
            app.mLabel_8.Text = 'm';
            app.roverx = uieditfield(app.Panel_8, 'numeric');
            app.roverx.ValueDisplayFormat = '%.4f';
            app.roverx.Value = -2364332.1699;
            app.rovery = uieditfield(app.Panel_8, 'numeric');
            app.rovery.ValueDisplayFormat = '%.4f';
            app.rovery.Value = 4870283.9758;
            app.roverz = uieditfield(app.Panel_8, 'numeric');
            app.roverz.ValueDisplayFormat = '%.4f';
            app.roverz.Value = -3360808.1988;
            app.mLabel_9 = uilabel(app.Panel_8);
            app.mLabel_9.Text = 'm';
            app.mLabel_10 = uilabel(app.Panel_8);
            app.mLabel_10.Text = 'm';
            app.mLabel_11 = uilabel(app.Panel_8);
            app.mLabel_11.Text = 'm';
            app.InitialParameter = uipanel(app.Panel_4);
            app.InitialParameter.Title = 'Select Initial Uncertainty Parameters';
            app.PositionEditFieldLabel = uilabel(app.InitialParameter);
            app.PositionEditFieldLabel.Text = 'Position';
            app.PosParameter = uieditfield(app.InitialParameter, 'numeric');
            app.PosParameter.ValueDisplayFormat = '%.1f';
            app.PosParameter.Value = 1;
            app.ReceiverClockLabel = uilabel(app.InitialParameter);
            app.ReceiverClockLabel.Text = 'Receiver Clock';
            app.RecClkParameter = uieditfield(app.InitialParameter, 'numeric');
            app.RecClkParameter.ValueDisplayFormat = '%.1f';
            app.RecClkParameter.Value = 1;
            app.TrophosphereLabel = uilabel(app.InitialParameter);
            app.TrophosphereLabel.Text = 'Trophosphere';
            app.TropParameter = uieditfield(app.InitialParameter, 'numeric');
            app.TropParameter.ValueDisplayFormat = '%.1f';
            app.TropParameter.Value = 0.5;
            app.PhaseAmbiguitiesLabel = uilabel(app.InitialParameter);
            app.PhaseAmbiguitiesLabel.Text = 'Phase Ambiguities';
            app.AmbParameter = uieditfield(app.InitialParameter, 'numeric');
            app.AmbParameter.ValueDisplayFormat = '%.1f';
            app.AmbParameter.Value = 2;
            app.PosLabel = uilabel(app.InitialParameter);
            app.PosLabel.Text = 'm';
            app.PosNoise2_2Label_2 = uilabel(app.InitialParameter);
            app.PosNoise2_2Label_2.Text = 'e';
            app.PosParameter2 = uieditfield(app.InitialParameter, 'numeric');
            app.PosParameter2.ValueDisplayFormat = '%.0f';
            app.PosParameter2.Value = 2;
            app.RecClkLabel2 = uilabel(app.InitialParameter);
            app.RecClkLabel2.Text = 'm';
            app.PosParameter2_2Label = uilabel(app.InitialParameter);
            app.PosParameter2_2Label.Text = 'e';
            app.RecClkParameter2 = uieditfield(app.InitialParameter, 'numeric');
            app.RecClkParameter2.ValueDisplayFormat = '%.0f';
            app.RecClkParameter2.Value = 5;
            app.tropLabel = uilabel(app.InitialParameter);
            app.tropLabel.Text = 'm';
            app.PosParameter2_3Label = uilabel(app.InitialParameter);
            app.PosParameter2_3Label.Text = 'e';
            app.TropParameter2 = uieditfield(app.InitialParameter, 'numeric');
            app.TropParameter2.ValueDisplayFormat = '%.0f';
            app.AmbLabel = uilabel(app.InitialParameter);
            app.AmbLabel.Text = 'm';
            app.PosParameter2_4Label = uilabel(app.InitialParameter);
            app.PosParameter2_4Label.Text = 'e';
            app.AmbParameter2 = uieditfield(app.InitialParameter, 'numeric');
            app.AmbParameter2.ValueDisplayFormat = '%.0f';
            app.AmbParameter2.Value = 1;
            app.SystemTimeDifferenceLabel_2 = uilabel(app.InitialParameter);
            app.SystemTimeDifferenceLabel_2.Text = 'System Time Difference';
            app.STDParameter = uieditfield(app.InitialParameter, 'numeric');
            app.STDParameter.ValueDisplayFormat = '%.1f';
            app.STDParameter.Value = 1;
            app.AmbParameter2_2Label = uilabel(app.InitialParameter);
            app.AmbParameter2_2Label.Text = 'e';
            app.STDParameter2 = uieditfield(app.InitialParameter, 'numeric');
            app.STDParameter2.ValueDisplayFormat = '%.0f';
            app.STDParameter2.Value = 2;
            app.STDLabel = uilabel(app.InitialParameter);
            app.STDLabel.Text = 'm';
            app.STDLabel_2 = uilabel(app.InitialParameter);
            app.STDLabel_2.Text = 'm';
            app.AmbParameter2_2Label_2 = uilabel(app.InitialParameter);
            app.AmbParameter2_2Label_2.Text = 'e';
            app.CBParameter2 = uieditfield(app.InitialParameter, 'numeric');
            app.CBParameter2.ValueDisplayFormat = '%.0f';
            app.CBParameter2.Value = 2;
            app.CodeBiasLabel = uilabel(app.InitialParameter);
            app.CodeBiasLabel.Text = 'Code Bias';
            app.CBParameter = uieditfield(app.InitialParameter, 'numeric');
            app.CBParameter.ValueDisplayFormat = '%.1f';
            app.CBParameter.Value = 1;
            app.STDLabel_3 = uilabel(app.InitialParameter);
            app.STDLabel_3.Text = 'm';
            app.AmbParameter2_2Label_3 = uilabel(app.InitialParameter);
            app.AmbParameter2_2Label_3.Text = 'e';
            app.InoParameter2 = uieditfield(app.InitialParameter, 'numeric');
            app.InoParameter2.ValueDisplayFormat = '%.0f';
            app.InoParameter2.Value = -2;
            app.InonsphereLabel = uilabel(app.InitialParameter);
            app.InonsphereLabel.Text = 'Inonsphere';
            app.InoParameter = uieditfield(app.InitialParameter, 'numeric');
            app.InoParameter.ValueDisplayFormat = '%.1f';
            app.InoParameter.Value = 4;
            app.STDLabel_4 = uilabel(app.InitialParameter);
            app.STDLabel_4.Text = 'm';
            app.AmbParameter2_2Label_4 = uilabel(app.InitialParameter);
            app.AmbParameter2_2Label_4.Text = 'e';
            app.PBParameter2 = uieditfield(app.InitialParameter, 'numeric');
            app.PBParameter2.ValueDisplayFormat = '%.0f';
            app.PBParameter2.Value = 2;
            app.PhaseBiasLabel = uilabel(app.InitialParameter);
            app.PhaseBiasLabel.Text = 'Phase Bias';
            app.PBParameter = uieditfield(app.InitialParameter, 'numeric');
            app.PBParameter.ValueDisplayFormat = '%.1f';
            app.PBParameter.Value = 1;
            app.STDLabel_5 = uilabel(app.InitialParameter);
            app.STDLabel_5.Text = 'm';
            app.AmbParameter2_2Label_5 = uilabel(app.InitialParameter);
            app.AmbParameter2_2Label_5.Text = 'e';
            app.IFBParameter2 = uieditfield(app.InitialParameter, 'numeric');
            app.IFBParameter2.ValueDisplayFormat = '%.0f';
            app.IFBParameter2.Value = 2;
            app.IFBLabel = uilabel(app.InitialParameter);
            app.IFBLabel.Text = 'IFB';
            app.IFBParameter = uieditfield(app.InitialParameter, 'numeric');
            app.IFBParameter.ValueDisplayFormat = '%.1f';
            app.IFBParameter.Value = 1;
            app.STDLabel_6 = uilabel(app.InitialParameter);
            app.STDLabel_6.Text = 'm';
            app.AmbParameter2_2Label_6 = uilabel(app.InitialParameter);
            app.AmbParameter2_2Label_6.Text = 'e';
            app.SatClkParameter2 = uieditfield(app.InitialParameter, 'numeric');
            app.SatClkParameter2.ValueDisplayFormat = '%.0f';
            app.SatClkParameter2.Value = 2;
            app.SatelliteClockLabel = uilabel(app.InitialParameter);
            app.SatelliteClockLabel.Text = 'Satellite Clock';
            app.SatClkParameter = uieditfield(app.InitialParameter, 'numeric');
            app.SatClkParameter.ValueDisplayFormat = '%.1f';
            app.SatClkParameter.Value = 1;
            app.basepostype = uicheckbox(app.Panel_4);
            app.basepostype.Text = 'RINEX头文件';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end